classdef Do_an_PPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        THCHNHPHNGPHPTNHLabel         matlab.ui.control.Label
        NLabel                        matlab.ui.control.Label
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        ChnphngphptmListBox           matlab.ui.control.ListBox
        ChnphngphptmListBoxLabel      matlab.ui.control.Label
        TmnghimButton                 matlab.ui.control.Button
        SlnlpEditField                matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        KtqunghimEditField            matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        NhpkhongphnlynghimEditField   matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhpphngtrnhEditField          matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel     matlab.ui.control.Label
        NhpsaischophpEditField        matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        NisuyButton                   matlab.ui.control.Button
        ChnphngphpnisuyListBox        matlab.ui.control.ListBox
        ChnphngphpnisuyListBoxLabel   matlab.ui.control.Label
        KtqunisuyEditField            matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        KtquathcnisuyEditField        matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        NhpgitrcnnisuyEditField       matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        NhpdliuyEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NhpdliuxEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        HiQuyTab                      matlab.ui.container.Tab
        HiquyButton                   matlab.ui.control.Button
        ChnphngphphiquyListBox        matlab.ui.control.ListBox
        ChnphngphphiquyListBoxLabel   matlab.ui.control.Label
        KtqudonEditField              matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        NhpdliuyEditField_2           matlab.ui.control.EditField
        NhpdliuyEditField_2Label      matlab.ui.control.Label
        NhpdliuxEditField_2           matlab.ui.control.EditField
        NhpdliuxEditField_2Label      matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        NhpgitrcntnhohmEditField_2    matlab.ui.control.EditField
        NhpgitrcntnhohmEditField_2Label  matlab.ui.control.Label
        KetquaEditField               matlab.ui.control.NumericEditField
        KtquButton                    matlab.ui.control.Button
        ChnphngphpohmListBox          matlab.ui.control.ListBox
        ChnphngphpohmListBoxLabel     matlab.ui.control.Label
        ChngitrsaisListBox            matlab.ui.control.ListBox
        ChngitrsaisListBoxLabel       matlab.ui.control.Label
        NhpbchEditField               matlab.ui.control.EditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhphmsEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        HocLabel                      matlab.ui.control.Label
        NhpdliuyEditField_3           matlab.ui.control.EditField
        NhpdliuyEditField_3Label      matlab.ui.control.Label
        NhpdliuxEditField_3           matlab.ui.control.EditField
        NhpdliuxEditField_3Label      matlab.ui.control.Label
        TchphnTab                     matlab.ui.container.Tab
        NhpcntnhtchphnEditField       matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel  matlab.ui.control.Label
        Ketqua_1EditField             matlab.ui.control.NumericEditField
        KtquButton_2                  matlab.ui.control.Button
        NhpNEditField                 matlab.ui.control.NumericEditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        ChnphngphptchphnListBox       matlab.ui.control.ListBox
        ChnphngphptchphnListBoxLabel  matlab.ui.control.Label
        HocLabel_2                    matlab.ui.control.Label
        NhphmsEditField_2             matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        NhpdliuyEditField_4           matlab.ui.control.EditField
        NhpdliuyEditField_4Label      matlab.ui.control.Label
        NhpdliuxEditField_4           matlab.ui.control.EditField
        NhpdliuxEditField_4Label      matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        MSSVEditField                 matlab.ui.control.NumericEditField
        MSSVEditFieldLabel            matlab.ui.control.Label
        VaitrEditField                matlab.ui.control.EditField
        VaitrEditFieldLabel           matlab.ui.control.Label
        Lp23DTV_CLC2Label             matlab.ui.control.Label
        GVHDThcsHunhQucThnhLabel      matlab.ui.control.Label
        CcthnhvinListBox              matlab.ui.control.ListBox
        CcthnhvinListBoxLabel         matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = private)

        function num_arr = clean_and_parse(app, str)
            % Hàm làm sạch dữ liệu nhập vào
            try
                if isempty(str), num_arr = []; return; end
                
                % Thay thế dấu phẩy thành khoảng trắng
                str_clean = strrep(str, ',', ' ');
                % Giữ lại số, dấu chấm, dấu trừ, chữ e (cho số mũ)
                str_clean = regexprep(str_clean, '[^0-9.\-eE]+', ' ');
                
                % Chuyển đổi
                num_arr = str2num(str_clean);
                num_arr = num_arr(:); % Chuyển thành vector cột
            catch
                num_arr = [];
            end
        end

        function f = convert_string_to_function(app, str)
            try
                str = lower(str);
                str = strrep(str, '^', '.^');
                str = strrep(str, '*', '.*');
                str = strrep(str, '/', './');
                f = str2func(['@(x) ' str]);
            catch
                f = [];
            end
        end
            
        function [root, iter] = logic_tim_nghiem(app, f, a, b, tol, method)
            % Logic tìm nghiệm: Chia đôi, Newton, Lặp
            root = NaN; iter = 0; % iter là số lần lặp ban đầu
            switch method
                case 'Chia đôi'
                    if f(a)*f(b) > 0, return; end
                    while (b - a)/2 > tol && iter < 1000
                        c = (a + b) / 2;
                        if f(c) == 0, break; end
                        if f(a)*f(c) < 0, b = c; else, a = c; end
                        iter = iter + 1;
                    end
                    root = (a + b) / 2;
                case 'Lặp'
                    x0 = (a + b) /2;
                    root = x0;
                    err = 1;
                    while err > tol && iter < 1000
                        x_new = f(root);
                        if isinf(x_new) || isnan(x_new)
                            break;
                        end
                        err = abs(x_new - root);
                        root = x_new;
                        iter = iter + 1;
                    end
                case 'Newton (tiếp tuyến)'
                    % Tính đạo hàm xấp xỉ
                    h = 1e-5;
                    df = @(x) (f(x+h) - f(x))/h;
                    x0 = (a+b)/2; root = x0; err = 1;
                    while err > tol && iter < 1000
                        val = f(root); dval = df(root);
                        if abs(dval) < 1e-10, break; end
                        x_new = root - val/dval;
                        err = abs(x_new - root);
                        root = x_new;
                        iter = iter + 1;
                    end
            end
        end
        
        function [result, str_poly] = logic_noi_suy(app, x, y, val, method)
            x = x(:); y = y(:); n = length(x);
            
            % 1. Tính giá trị dự đoán (như cũ)
            result = 0;
            if strcmp(method, 'Lagrange')
                for i = 1:n
                    L = 1;
                    for j = 1:n
                        if i ~= j, L = L * (val - x(j)) / (x(i) - x(j)); end
                    end
                    result = result + y(i) * L;
                end
            else 
                d = zeros(n, n); d(:,1) = y;
                for j = 2:n
                    for i = 1:n-j+1, d(i,j) = (d(i+1,j-1) - d(i,j-1)) / (x(i+j-1) - x(i)); end
                end
                result = d(1,1); pt = 1;
                for j = 1:n-1, pt = pt * (val - x(j)); result = result + d(1,j+1) * pt; end
            end
            
            % 2. Tạo chuỗi đa thức hiển thị (vd: y = 2x^2 + 3x)
            % Sử dụng polyfit để tìm hệ số đa thức
            if n > 1
                % Dùng polyfit để lấy hệ số đa thức (bậc n-1 đi qua n điểm)
                % Chú ý: Với cùng tập dữ liệu, Newton và Lagrange ra cùng 1 đa thức
                p = polyfit(x, y, n-1); 
                
                str_poly = 'y = ';
                for i = 1:length(p)
                    pow = length(p) - i;
                    coef = p(i);
                    
                    if abs(coef) < 1e-5, continue; end % Bỏ qua hệ số quá nhỏ
                    
                    % Xử lý dấu
                    if coef >= 0 && i > 1
                        sign_str = ' + ';
                    elseif coef < 0
                        sign_str = ' - ';
                        coef = abs(coef);
                    else
                        sign_str = '';
                    end
                    
                    % Xử lý x^n
                    if pow == 0
                        term = sprintf('%.4f', coef);
                    elseif pow == 1
                        term = sprintf('%.4f*x', coef);
                    else
                        term = sprintf('%.4f*x^%d', coef, pow);
                    end
                    
                    str_poly = [str_poly, sign_str, term];
                end
                
                if strcmp(str_poly, 'y = '), str_poly = 'y = 0'; end % Trường hợp đa thức 0
            else
                str_poly = sprintf('y = %.4f', y(1));
            end
        end
        
        function [y_pred, eq_str, x_fit, y_fit] = logic_hoi_quy(app, x, y, val_predict, method)
            x = x(:); y = y(:); % SỬA LỖI: Làm phẳng vector (Fix lỗi Inputs must be scalars)
            
            % Tính toán khoảng vẽ đồ thị an toàn
            min_x = min(x); max_x = max(x);
            if isempty(min_x), min_x = 0; max_x = 1; end
            x_fit = linspace(min_x, max_x, 100);
            
            if strcmp(method, 'Tuyến tính')
                p = polyfit(x, y, 1);
                y_pred = polyval(p, val_predict);
                eq_str = sprintf('y = %.4fx + %.4f', p(1), p(2));
                y_fit = polyval(p, x_fit);
            elseif strcmp(method, 'Hàm mũ')
                % Cần xử lý log(y) > 0 hoặc dùng try-catch
                try
                    p = polyfit(x, log(y), 1);
                    B = p(1); A = exp(p(2));
                    y_pred = A * exp(B * val_predict);
                    eq_str = sprintf('y = %.4f * e^(%.4fx)', A, B);
                    y_fit = A * exp(B * x_fit);
                catch
                    y_pred = NaN; eq_str = 'Lỗi dữ liệu (y<=0)'; x_fit=[]; y_fit=[];
                end
            else 
                try
                    p = polyfit(log(x), y, 1);
                    B = p(1); A = p(2);
                    y_pred = A + B * log(val_predict);
                    eq_str = sprintf('y = %.4f + %.4f*ln(x)', A, B);
                    y_fit = A + B * log(x_fit);
                catch
                    y_pred = NaN; eq_str = 'Lỗi dữ liệu (x<=0)'; x_fit=[]; y_fit=[];
                end
            end
        end

        
function res = logic_dao_ham_data(app, x, y, x0, method)
            res = NaN;
            % Tìm vị trí của x0 trong mảng x (so sánh xấp xỉ)
            idx = find(abs(x - x0) < 1e-9, 1); 
            
            if isempty(idx)
                error('Giá trị X cần tính đạo hàm không có trong dữ liệu nhập vào.');
            end
            
            n = length(x);
            
            if strcmp(method, 'Xấp xỉ tiến')
                if idx < n
                    res = (y(idx+1) - y(idx)) / (x(idx+1) - x(idx));
                else
                    error('Không thể tính Xấp xỉ Tiến tại điểm cuối cùng (chỉ số vượt quá mảng).');
                end
            elseif strcmp(method, 'Lùi')
                if idx > 1
                    res = (y(idx) - y(idx-1)) / (x(idx) - x(idx-1));
                else
                    error('Không thể tính Xấp xỉ Lùi tại điểm đầu tiên (chỉ số bằng 0).');
                end
            else % Trung tâm
                if idx > 1 && idx < n
                    res = (y(idx+1) - y(idx-1)) / (x(idx+1) - x(idx-1));
                else
                    error('Không thể tính Xấp xỉ Trung tâm tại điểm đầu hoặc cuối.');
                end
            end
        end

        function res = logic_dao_ham_func(app, f, x0, h, method)
            if strcmp(method, 'Xấp xỉ tiến'), res = (f(x0 + h) - f(x0)) / h;
            elseif strcmp(method, 'Lùi'), res = (f(x0) - f(x0 - h)) / h;
            else, res = (f(x0 + h) - f(x0 - h)) / (2*h);
            end
        end

        function result = logic_tich_phan(app, x, y, method)
            % Hàm tính tích phân chung cho cả dữ liệu và hàm số (sau khi đã đổi ra x,y)
            n_points = length(x);
            if n_points < 2, result = 0; return; end
            
            % Kiểm tra khoảng cách đều (cho Simpson)
            h_diff = diff(x);
            is_uniform = all(abs(h_diff - h_diff(1)) < 1e-9);
            h = mean(h_diff);
            
            if strcmp(method, 'Hình thang')
                result = trapz(x, y);
                
            elseif strcmp(method, 'Simpson 1/3')
                if ~is_uniform, error('Simpson 1/3 yêu cầu x cách đều nhau.'); end
                if mod(n_points - 1, 2) ~= 0, error('Simpson 1/3 yêu cầu số khoảng N chẵn (số điểm lẻ).'); end
                
                so_le = sum(y(2:2:end-1));
                so_chan = sum(y(3:2:end-2));
                result = (h/3) * (y(1) + 4*so_le + 2*so_chan + y(end));
                
            elseif strcmp(method, 'Simpson 3/8')
                if ~is_uniform, error('Simpson 3/8 yêu cầu x cách đều nhau.'); end
                if mod(n_points - 1, 3) ~= 0, error('Simpson 3/8 yêu cầu số khoảng N chia hết cho 3.'); end
                
                % Tính thủ công Simpson 3/8
                result = 0;
                for i = 1:3:(n_points-1)
                    result = result + (3*h/8) * (y(i) + 3*y(i+1) + 3*y(i+2) + y(i+3));
                end
            else
                result = 0;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TmnghimButton
        function TmnghimButtonPushed(app, event)
           try
                % 1. Lấy Input
                str_func = app.NhpphngtrnhEditField.Value;
                str_range = app.NhpkhongphnlynghimEditField.Value;
                tol = app.NhpsaischophpEditField.Value;
                method = app.ChnphngphptmListBox.Value;
                
                % 2. Chuẩn bị dữ liệu
                f = app.convert_string_to_function(str_func);
                range = str2num(str_range);
                
                if isempty(f) || length(range)<2
                    uialert(app.UIFigure, 'Lỗi nhập liệu!', 'Lỗi'); return;
                end
                
                % 3. GỌI HELPER FUNCTION
                [root, iter] = app.logic_tim_nghiem(f, range(1), range(2), tol, method);
                
                % 4. Xuất Output
                app.KtqunghimEditField.Value = root;
                app.SlnlpEditField.Value = iter;
                
                % 5. Vẽ đồ thị
                x_vals = linspace(range(1)-1, range(2)+1, 100);
                plot(app.UIAxes, x_vals, f(x_vals), 'b-');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, x_vals, zeros(size(x_vals)), 'k--');
                plot(app.UIAxes, root, f(root), 'ro', 'MarkerFaceColor', 'r');
                hold(app.UIAxes, 'off'); grid(app.UIAxes, 'on');
           catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
           end
        end

        % Button pushed function: NisuyButton
        function NisuyButtonPushed(app, event)
             try
                % 1. Lấy Input
                x = str2num(app.NhpdliuxEditField.Value);
                y = str2num(app.NhpdliuyEditField.Value);
                val = app.NhpgitrcnnisuyEditField.Value;
                method = app.ChnphngphpnisuyListBox.Value;
                
                if length(x) ~= length(y)
                    uialert(app.UIFigure, 'Lỗi kích thước X, Y', 'Lỗi'); return;
                end
                
                % 2. GỌI HELPER FUNCTION
                [result, str_poly] = app.logic_noi_suy(x, y, val, method);
                
                % 3. Xuất Output
                app.KtquathcnisuyEditField.Value = str_poly;
                app.KtqunisuyEditField.Value = result;
                
                % 4. Vẽ
                plot(app.UIAxes2, x, y, 'ko', 'MarkerFaceColor', 'k');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2, val, result, 'rs', 'MarkerFaceColor', 'r');
                hold(app.UIAxes2, 'off'); grid(app.UIAxes2, 'on');
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
             end
             
        end

        % Button pushed function: HiquyButton
        function HiquyButtonPushed(app, event)
             try
                % 1. Đọc dữ liệu thô
                x_raw = app.NhpdliuxEditField_2.Value;
                y_raw = app.NhpdliuyEditField_2.Value;
                
                % 2. Làm sạch
                x = app.clean_and_parse(x_raw);
                y = app.clean_and_parse(y_raw);
                val_predict = app.NhpgitrcndonEditField.Value;
                method = app.ChnphngphphiquyListBox.Value;
                
                if length(x) ~= length(y)
                    uialert(app.UIFigure, sprintf('Độ dài X (%d) và Y (%d) không khớp', length(x), length(y)), 'Lỗi');
                    return;
                end
                
                [y_pred, eq_str, x_fit, y_fit] = app.logic_hoi_quy(x, y, val_predict, method);
                
                app.KtquphngtrnhhiquyEditField.Value = eq_str;
                app.KtqudonEditField.Value = y_pred;
                
                cla(app.UIAxes3); % Xóa biểu đồ cũ
                plot(app.UIAxes3, x, y, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8); % Điểm dữ liệu
                hold(app.UIAxes3, 'on');
                plot(app.UIAxes3, x_fit, y_fit, 'b-', 'LineWidth', 1.5); % Đường hồi quy
                plot(app.UIAxes3, val_predict, y_pred, 'g*', 'MarkerSize', 12); % Điểm dự đoán
                hold(app.UIAxes3, 'off'); grid(app.UIAxes3, 'on');
                title(app.UIAxes3, ['Hồi quy: ' method]);
                
            catch ME
                uialert(app.UIFigure, ['Lỗi Hồi quy: ' ME.message], 'Lỗi');
             end
        end

        % Button pushed function: KtquButton
        function KtquButtonPushed(app, event)
            try
                % --- CHỈNH SỬA LẠI DÒNG NÀY ---
                % Chuyển đổi chuỗi nhập vào thành số (double)
                x0 = str2double(app.NhpgitrcntnhohmEditField_2.Value); 
                % ------------------------------
                
                method = app.ChnphngphpohmListBox.Value;
                
                % 1. Thử đọc hàm số
                str_func = app.NhphmsEditField.Value;
                f = app.convert_string_to_function(str_func);
                
                res = NaN;
                
                if ~isempty(f)
                    % Trường hợp 1: Có hàm số
                    h = str2double(app.NhpbchEditField.Value);
                    if isnan(h), uialert(app.UIFigure, 'Vui lòng nhập bước h.', 'Lỗi'); return; end
                    
                    % Kiểm tra x0 có hợp lệ không
                    if isnan(x0), uialert(app.UIFigure, 'Giá trị X không hợp lệ.', 'Lỗi'); return; end
                    
                    res = app.logic_dao_ham_func(f, x0, h, method);
                else
                    % Trường hợp 2: Không có hàm số -> Dùng dữ liệu X, Y
                    x = app.clean_and_parse(app.NhpdliuxEditField_3.Value);
                    y = app.clean_and_parse(app.NhpdliuyEditField_3.Value);
                    
                    if isempty(x) || isempty(y)
                        uialert(app.UIFigure, 'Vui lòng nhập Hàm số HOẶC Dữ liệu X, Y.', 'Thiếu thông tin'); return;
                    end
                    
                    if length(x) ~= length(y)
                        uialert(app.UIFigure, 'Kích thước X và Y không bằng nhau.', 'Lỗi'); return;
                    end
                    
                    % Kiểm tra x0 có hợp lệ không
                    if isnan(x0), uialert(app.UIFigure, 'Giá trị X cần tính đạo hàm không hợp lệ.', 'Lỗi'); return; end

                    % Gọi hàm logic_dao_ham_data
                    res = app.logic_dao_ham_data(x, y, x0, method);
                end
                
                app.KetquaEditField.Limits = [-inf inf];
                app.KetquaEditField.Value = res;
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi Đạo hàm');
            end
        end

        % Button pushed function: KtquButton_2
        function KtquButton_2Pushed(app, event)
           try
                method = app.ChnphngphptchphnListBox.Value;
                str_func = app.NhphmsEditField_2.Value;
                
                x = []; y = [];
                
                if ~isempty(str_func)
                    % 1. CHẾ ĐỘ HÀM SỐ
                    f = app.convert_string_to_function(str_func);
                    limits = app.clean_and_parse(app.NhpcntnhtchphnEditField.Value);
                    N = app.NhpNEditField.Value;
                    
                    if length(limits) < 2, uialert(app.UIFigure, 'Cận phải có 2 số [a b]', 'Lỗi'); return; end
                    if isempty(N) || N<=0, uialert(app.UIFigure, 'N phải > 0', 'Lỗi'); return; end
                    
                    a = limits(1); b = limits(2);
                    x = linspace(a, b, N+1);
                    y = f(x);
                else
                    % 2. CHẾ ĐỘ DỮ LIỆU X, Y
                    x = app.clean_and_parse(app.NhpdliuxEditField_4.Value);
                    y = app.clean_and_parse(app.NhpdliuyEditField_4.Value);
                    
                    if isempty(x) || isempty(y), uialert(app.UIFigure, 'Nhập Hàm số HOẶC Dữ liệu X, Y', 'Thiếu thông tin'); return; end
                    if length(x) ~= length(y), uialert(app.UIFigure, 'Kích thước X, Y lệch', 'Lỗi'); return; end
                end
                
                % Gọi logic tính toán chung
                result = app.logic_tich_phan(x, y, method);
                
                app.Ketqua_1EditField.Limits = [-inf inf];
                app.Ketqua_1EditField.Value = result;
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi Tích phân');
            end
        end

        % Value changed function: ChnphngphptmListBox
        function ChnphngphptmListBoxValueChanged(app, event)
            value = app.ChnphngphptmListBox.Value;
            if strcmp(value, 'Lặp')
                app.NhpphngtrnhEditFieldLabel.Text = 'Nhâp hàm lặp g(x)';
                app.NhpphngtrnhEditFieldLabel.FontColor = [1 0 0];
                uialert(app.UIFigure, ['Với phương pháp Lặp đơn, bạn cần biến đổi phương trình f(x) = 0 về dạng x = g(x), sau đó chỉ nhập vế phải g(x) vào ô.'], 'Hướng dẫn nhập liệu', 'Icon','info');
            else
                app.NhpphngtrnhEditFieldLabel.Text = 'Nhâp phương trình f(x)';
                app.NhpphngtrnhEditFieldLabel.FontColor = [0 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.NhpkhongphnlynghimEditField = uieditfield(app.NghimTab, 'text');
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.TmnghimButton = uibutton(app.NghimTab, 'push');
            app.TmnghimButton.ButtonPushedFcn = createCallbackFcn(app, @TmnghimButtonPushed, true);
            app.TmnghimButton.Icon = fullfile(pathToMLAPP, 'search.png');
            app.TmnghimButton.Text = 'Tìm nghiệm';
            app.ChnphngphptmListBoxLabel = uilabel(app.NghimTab);
            app.ChnphngphptmListBoxLabel.Text = 'Chọn phương pháp tìm';
            app.ChnphngphptmListBox = uilistbox(app.NghimTab);
            app.ChnphngphptmListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ChnphngphptmListBox.ValueChangedFcn = createCallbackFcn(app, @ChnphngphptmListBoxValueChanged, true);
            app.ChnphngphptmListBox.Value = 'Chia đôi';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ChnphngphpnisuyListBoxLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyListBoxLabel.Text = 'Chọn phương pháp nội suy';
            app.ChnphngphpnisuyListBox = uilistbox(app.NisuyTab);
            app.ChnphngphpnisuyListBox.Items = {'Newton', 'Lagrange'};
            app.ChnphngphpnisuyListBox.Value = 'Newton';
            app.NisuyButton = uibutton(app.NisuyTab, 'push');
            app.NisuyButton.ButtonPushedFcn = createCallbackFcn(app, @NisuyButtonPushed, true);
            app.NisuyButton.Text = 'Nội suy';
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.UIAxes3 = uiaxes(app.HiQuyTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.NhpdliuxEditField_2Label = uilabel(app.HiQuyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField_2 = uieditfield(app.HiQuyTab, 'text');
            app.NhpdliuyEditField_2Label = uilabel(app.HiQuyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField_2 = uieditfield(app.HiQuyTab, 'text');
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiQuyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiQuyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiQuyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.HiQuyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiQuyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiQuyTab, 'numeric');
            app.ChnphngphphiquyListBoxLabel = uilabel(app.HiQuyTab);
            app.ChnphngphphiquyListBoxLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChnphngphphiquyListBox = uilistbox(app.HiQuyTab);
            app.ChnphngphphiquyListBox.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChnphngphphiquyListBox.Value = 'Tuyến tính';
            app.HiquyButton = uibutton(app.HiQuyTab, 'push');
            app.HiquyButton.ButtonPushedFcn = createCallbackFcn(app, @HiquyButtonPushed, true);
            app.HiquyButton.Text = 'Hồi quy';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuxEditField_3Label.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_3Label = uilabel(app.ohmTab);
            app.NhpdliuyEditField_3Label.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.NhphmsEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhpbchEditField = uieditfield(app.ohmTab, 'text');
            app.ChngitrsaisListBoxLabel = uilabel(app.ohmTab);
            app.ChngitrsaisListBoxLabel.Text = 'Chọn giá trị sai số';
            app.ChngitrsaisListBox = uilistbox(app.ohmTab);
            app.ChngitrsaisListBox.Items = {'O(h)', 'O(h^2)'};
            app.ChngitrsaisListBox.Value = 'O(h)';
            app.ChnphngphpohmListBoxLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmListBoxLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChnphngphpohmListBox = uilistbox(app.ohmTab);
            app.ChnphngphpohmListBox.Items = {'Xấp xỉ tiến', 'Lùi', 'Trung tâm'};
            app.ChnphngphpohmListBox.Value = 'Xấp xỉ tiến';
            app.KtquButton = uibutton(app.ohmTab, 'push');
            app.KtquButton.ButtonPushedFcn = createCallbackFcn(app, @KtquButtonPushed, true);
            app.KtquButton.Text = 'Kết quả';
            app.KetquaEditField = uieditfield(app.ohmTab, 'numeric');
            app.NhpgitrcntnhohmEditField_2Label = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditField_2Label.Text = 'Nhập giá trị cần tính đạo hàm';
            app.NhpgitrcntnhohmEditField_2 = uieditfield(app.ohmTab, 'text');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_4Label.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField_2Label = uilabel(app.TchphnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.NhphmsEditField_2 = uieditfield(app.TchphnTab, 'text');
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.ChnphngphptchphnListBoxLabel = uilabel(app.TchphnTab);
            app.ChnphngphptchphnListBoxLabel.Text = 'Chọn phương pháp tích phân';
            app.ChnphngphptchphnListBox = uilistbox(app.TchphnTab);
            app.ChnphngphptchphnListBox.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChnphngphptchphnListBox.Value = 'Hình thang';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhpNEditField = uieditfield(app.TchphnTab, 'numeric');
            app.KtquButton_2 = uibutton(app.TchphnTab, 'push');
            app.KtquButton_2.ButtonPushedFcn = createCallbackFcn(app, @KtquButton_2Pushed, true);
            app.KtquButton_2.Text = 'Kết quả';
            app.Ketqua_1EditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân';
            app.NhpcntnhtchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'z7246115938870_6af4d794ee309f0ce10c6c6a799b997f.jpg');
            app.CcthnhvinListBoxLabel = uilabel(app.GiithiunhmTab);
            app.CcthnhvinListBoxLabel.Text = 'Các thành viên';
            app.CcthnhvinListBox = uilistbox(app.GiithiunhmTab);
            app.CcthnhvinListBox.Items = {'Phạm Minh Ngọc', 'Lê Nguyễn Thùy Dương', 'Vũ Đức Quang'};
            app.CcthnhvinListBox.Value = 'Phạm Minh Ngọc';
            app.GVHDThcsHunhQucThnhLabel = uilabel(app.GiithiunhmTab);
            app.GVHDThcsHunhQucThnhLabel.Text = 'GVHD: Thạc sĩ Huỳnh Quốc Thịnh';
            app.Lp23DTV_CLC2Label = uilabel(app.GiithiunhmTab);
            app.Lp23DTV_CLC2Label.Text = 'Lớp: 23DTV_CLC2';
            app.VaitrEditFieldLabel = uilabel(app.GiithiunhmTab);
            app.VaitrEditFieldLabel.Text = 'Vai trò';
            app.VaitrEditField = uieditfield(app.GiithiunhmTab, 'text');
            app.MSSVEditFieldLabel = uilabel(app.GiithiunhmTab);
            app.MSSVEditFieldLabel.Text = 'MSSV';
            app.MSSVEditField = uieditfield(app.GiithiunhmTab, 'numeric');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo-KHTN.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'logo-khoa.jpg');
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'ĐỒ ÁN';
            app.THCHNHPHNGPHPTNHLabel = uilabel(app.UIFigure);
            app.THCHNHPHNGPHPTNHLabel.Text = 'THỰC HÀNH PHƯƠNG PHÁP TÍNH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Do_an_PPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end